classdef Fly_Wing_Fixer_2017b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        BrowseMenu                 matlab.ui.container.Menu
        LoadMenu                   matlab.ui.container.Menu
        StartMenu                  matlab.ui.container.Menu
        SaveasMenu                 matlab.ui.container.Menu
        frame_indexSpinnerLabel    matlab.ui.control.Label
        frame_indexSpinner         matlab.ui.control.Spinner
        pathEditFieldLabel         matlab.ui.control.Label
        pathEditField              matlab.ui.control.EditField
        FixButton                  matlab.ui.control.Button
        WingDetectedSwitchLabel    matlab.ui.control.Label
        WingDetectedSwitch         matlab.ui.control.ToggleSwitch
        CompleteLabel              matlab.ui.control.Label
        CompleteLamp               matlab.ui.control.Lamp
        ProcessingLabel            matlab.ui.control.Label
        ProcessingLamp             matlab.ui.control.Lamp
        QuickSaveSwitchLabel       matlab.ui.control.Label
        QuickSaveSwitch            matlab.ui.control.ToggleSwitch
        ErrorFilterPanel           matlab.ui.container.Panel
        ErrorTypeDropDownLabel     matlab.ui.control.Label
        ErrorTypeDropDown          matlab.ui.control.DropDown
        ErrorBoutListBoxLabel      matlab.ui.control.Label
        ErrorBoutListBox           matlab.ui.control.ListBox
        RefreshButton              matlab.ui.control.Button
        InterpolationPanel         matlab.ui.container.Panel
        BeginEditFieldLabel        matlab.ui.control.Label
        BeginEditField             matlab.ui.control.NumericEditField
        EndEditFieldLabel          matlab.ui.control.Label
        EndEditField               matlab.ui.control.NumericEditField
        InterpolateButton          matlab.ui.control.Button
        SetBegin                   matlab.ui.control.Button
        SetEnd                     matlab.ui.control.Button
        VideoLengthEditFieldLabel  matlab.ui.control.Label
        VideoLengthEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        wingl % Description
        wingr % Description
        video_path % Description
        frame_index % Description
        track % Description
        file % Description
        wing_length
        sex_index % Description
        dir % Description
        trough % Description
        wing_detected % Description
        loaded_file % Description
        inter_begin % Description
        inter_end % Description
        visible % Description
        inter_begin_anglel % Description
        inter_begin_angler % Description
        inter_end_anglel % Description
        inter_end_angler % Description
    end

    methods (Access = private)
    
        function h = exe_imline(~,position)
            h = imline(gca,position);
        end
        
        function start_exe(app)
            ellipse_x0 = app.track.x(app.frame_index);
            ellipse_y0 = app.track.y(app.frame_index);
            centroid_coordinate = [ellipse_x0 ellipse_y0];
            ellipse_long_rad = app.track.a(app.frame_index)*2;
            ellipse_short_rad = app.track.b(app.frame_index)*2;
            ellipse_theta = app.track.theta(app.frame_index);
            
            wingl_end = [app.track.xwingl(app.frame_index) app.track.ywingl(app.frame_index)];
            wingr_end = [app.track.xwingr(app.frame_index) app.track.ywingr(app.frame_index)];
            if strcmp(app.wing_detected,'One')
                wingl_ends = app.rad2pos(ellipse_theta,centroid_coordinate,app.wing_length);
                wingr_ends = wingl_ends;
                trough_ends = app.rad2pos(ellipse_theta,centroid_coordinate,app.wing_length*0.6);
            elseif strcmp(app.wing_detected,'Two')
                wingl_ends = [centroid_coordinate;wingl_end];
                wingr_ends = [centroid_coordinate;wingr_end];
                trough_ends = app.rad2pos(app.track.wing_trough_angle(app.frame_index)+ellipse_theta,centroid_coordinate,app.wing_length*0.6);
            end
            
            set(gcf,'Visible', app.visible)
            app.get_frame(app.video_path,app.frame_index)
            
            app.draw_ellipse(ellipse_x0,ellipse_y0,ellipse_long_rad,ellipse_short_rad,ellipse_theta)
            head_ends = app.rad2pos(ellipse_theta+pi,centroid_coordinate,ellipse_long_rad*1.1);
            drawArrow([head_ends(1,1);head_ends(1,2)],[head_ends(2,1);head_ends(2,2)],'r');
            
            xlim([centroid_coordinate(1) - 100; centroid_coordinate(1)+100])
            ylim([centroid_coordinate(2) - 100; centroid_coordinate(2)+100])
            
            plot([centroid_coordinate(1),wingl_end(1)],[centroid_coordinate(2),wingl_end(2)],'--r')
            plot([centroid_coordinate(1),wingr_end(1)],[centroid_coordinate(2),wingr_end(2)],'--b')
            app.wingl = app.exe_imline(wingl_ends);
            app.wingr = app.exe_imline(wingr_ends);
            app.trough = app.exe_imline(trough_ends);
            setColor(app.trough,[157 188 64]/256)
            setColor(app.wingl,[224 163 46]/256)
            setColor(app.wingr,[8 133 161]/256)
        end
        
        function theta = get_orientation(~,position)
            theta = -(atan2((position(1,1) - position(2,1)),(position(1,2) - position(2,2)))- pi/2);
        end
        
        function position = rad2pos(~,rad,ini_point,length)
            position(1,:) = ini_point;
            position(2,1) = ini_point(1) + cos(rad-pi)*length;
            position(2,2) = ini_point(2) + sin(rad-pi)*length;
        end
        
        function new_position = get_resized_wing_coordinate(~,wing_length,position)
            x_diff = (wing_length * diff(position(:,1)))/sqrt(diff(position(:,1))^2 + diff(position(:,2))^2);
            y_diff = (wing_length * diff(position(:,2)))/sqrt(diff(position(:,1))^2 + diff(position(:,2))^2);
            x = position(1,1) + x_diff;
            y = position(1,2) + y_diff;
            new_position = [position(1,:);x,y];
        end
        
        function draw_ellipse(~,x_coordinate,y_coordinate,long_rad,short_rad,theta)
            t = linspace(0,2*pi,100);
            x = x_coordinate + long_rad*cos(t)*cos(theta) - short_rad*sin(t)*sin(theta);
            y = y_coordinate + short_rad*sin(t)*cos(theta) + long_rad*cos(t)*sin(theta);
            plot(x,y,'r');
        end
        
        function get_frame(~,video_file,frame_index)
            hold off
            [readframe,~,~,~] = get_readframe_fcn(video_file);
            imshow(readframe(frame_index),[])
            hold on
        end
        
        function ave = average_wing_length(~,trx)
            sq = zeros(1,length(trx.a));
            for i = 1:length(trx.a)
                sq(i) = (trx.xwingl(i)  - trx.x(i))^2 + (trx.ywingl(i)  - trx.y(i))^2;
            end
            ave = mean(sqrt(sq));
        end
        
        function interpolate(app)
            app.CompleteLamp.Enable = 'off';
            app.ProcessingLamp.Enable = 'on';
            app.visible = 'off';
            inter_index = app.inter_begin:app.inter_end;
            if strcmp(app.wing_detected,'One')
                app.track.wing_anglel(inter_index) = zeros(1,length(inter_index));
                app.track.wing_angler(inter_index) = zeros(1,length(inter_index));
                app.track.wing_trough_angle(inter_index) = zeros(1,length(inter_index));
                app.track.nwingsdetected(inter_index) = 1;
            elseif strcmp(app.wing_detected,'Two')
                app.track.wing_anglel(inter_index) = linspace(app.inter_begin_anglel,app.inter_end_anglel,length(inter_index));
                app.track.wing_angler(inter_index) = linspace(app.inter_begin_angler,app.inter_end_angler,length(inter_index));
                app.track.wing_trough_angle(inter_index) = mean([app.track.wing_anglel(inter_index);app.track.wing_angler(inter_index)]);
                app.track.nwingsdetected(inter_index) = 2;
            end
            for index = inter_index
                ellipse_x0 = app.track.x(index);
                ellipse_y0 = app.track.y(index);
                centroid_coordinate = [ellipse_x0 ellipse_y0];
                wingl_ends = app.rad2pos(app.track.wing_anglel(index)+app.track.theta(index),centroid_coordinate,app.wing_length);
                wingr_ends = app.rad2pos(app.track.wing_angler(index)+app.track.theta(index),centroid_coordinate,app.wing_length);
                app.track.xwingl(index) = wingl_ends(2,1);
                app.track.ywingl(index) = wingl_ends(2,2);
                app.track.xwingr(index) = wingr_ends(2,1);
                app.track.ywingr(index) = wingr_ends(2,2);
            end
            app.visible = 'on';
            app.ProcessingLamp.Enable = 'off';
            app.CompleteLamp.Enable = 'on';
        end
        
        function fix_exe(app)
            [wingl_new_positoin, wingr_new_positoin] = get_new_position(app);
            
            delete(app.wingl)
            delete(app.wingr)
            app.wingl = app.exe_imline(wingl_new_positoin);
            app.wingr = app.exe_imline(wingr_new_positoin);
            setColor(app.wingl,[224 163 46]/256)
            setColor(app.wingr,[8 133 161]/256)
            
            app.track.xwingl(app.frame_index) = wingl_new_positoin(2,1);
            app.track.ywingl(app.frame_index) = wingl_new_positoin(2,2);
            app.track.xwingr(app.frame_index) = wingr_new_positoin(2,1);
            app.track.ywingr(app.frame_index) = wingr_new_positoin(2,2);
            
            app.track.wing_anglel(app.frame_index) = app.get_orientation(wingl_new_positoin) - app.track.theta(app.frame_index);
            app.track.wing_angler(app.frame_index) = app.get_orientation(wingr_new_positoin) - app.track.theta(app.frame_index);
            app.track.wing_trough_angle(app.frame_index) = mean([app.track.wing_anglel(app.frame_index),app.track.wing_angler(app.frame_index)]);
            if strcmp(app.wing_detected,'One')
                app.track.nwingsdetected(app.frame_index) = 1;
            elseif strcmp(app.wing_detected,'Two')
                app.track.nwingsdetected(app.frame_index) = 2;
            end
            % Trough Need to be fixed somehow
        end
        
        function [wingl_new_positoin, wingr_new_positoin] = get_new_position(app)
             posl = getPosition(app.wingl);
             posr = getPosition(app.wingr);
             wingl_new_positoin = app.get_resized_wing_coordinate(app.wing_length,posl);
             wingr_new_positoin = app.get_resized_wing_coordinate(app.wing_length,posr);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.frame_index = 1;
            app.wing_detected = 'Two';
            app.CompleteLamp.Enable = 'off';
            app.ProcessingLamp.Enable = 'off';
            app.visible = 'on';
            app.inter_begin = 1;
            app.inter_end = 1;
            addpath(genpath('read_fmf'))
        end

        % Menu selected function: StartMenu
        function StartButtonPushed(app, event)
            app.CompleteLamp.Enable = 'off';
            app.ProcessingLamp.Enable = 'on';
            start_exe(app)
            app.ProcessingLamp.Enable = 'off';
            app.CompleteLamp.Enable = 'on';
        end

        % Menu selected function: BrowseMenu
        function browseButtonPushed(app, event)
            app.dir = uigetdir('Z:\April\test');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            app.pathEditField.Value = app.dir;
            app.video_path = strcat(app.dir,'\video.fmf');
            if exist(strcat(app.dir,'\temp_fix_wing.mat'), 'file') == 2
                app.file = load(strcat(app.dir,'\temp_fix_wing.mat'));
            else
                app.file = load(strcat(app.dir,'\video.mat'));
            end
            if app.file.trx(1).sex == 'M'
                app.sex_index = 1;
            elseif app.file.trx(2).sex == 'M'
                app.sex_index = 2;
            end
            app.track = app.file.trx(app.sex_index);
            app.wing_length = app.average_wing_length(app.track);
            app.VideoLengthEditField.Value = length(app.track.x);
            app.frame_indexSpinner.Limits = [1 length(app.track.x)];
            app.BeginEditField.Limits = [1 length(app.track.x)];
            app.EndEditField.Limits = [1 length(app.track.x)];
        end

        % Value changed function: frame_indexSpinner
        function frame_indexSpinnerValueChanged(app, event)
            value = app.frame_indexSpinner.Value;
            app.frame_index = value;
            app.CompleteLamp.Enable = 'off';
            app.ProcessingLamp.Enable = 'on';
            start_exe(app)
            app.ProcessingLamp.Enable = 'off';
            app.CompleteLamp.Enable = 'on';
        end

        % Button pushed function: FixButton
        function FixButtonPushed(app, event)
            fix_exe(app)
            
            app.frame_indexSpinner.Value = app.frame_indexSpinner.Value + 1;
            app.frame_index = app.frame_indexSpinner.Value;
            
            app.CompleteLamp.Enable = 'off';
            app.ProcessingLamp.Enable = 'on';
            start_exe(app)
            app.ProcessingLamp.Enable = 'off';
            app.CompleteLamp.Enable = 'on';
        end

        % Value changed function: WingDetectedSwitch
        function WingDetectedSwitchValueChanged(app, event)
            value = app.WingDetectedSwitch.Value;
            app.wing_detected = value;
            if strcmp(app.wing_detected,'One')
                app.SetBegin.Enable = 'off';
                app.SetEnd.Enable = 'off';
                app.BeginEditField.Editable = 'on';
                app.EndEditField.Editable = 'on';
            elseif strcmp(app.wing_detected,'Two')
                app.SetBegin.Enable = 'on';
                app.SetEnd.Enable = 'on';
                app.BeginEditField.Editable = 'off';
                app.EndEditField.Editable = 'off';
            end
            app.CompleteLamp.Enable = 'off';
            app.ProcessingLamp.Enable = 'on';
            start_exe(app)
            app.ProcessingLamp.Enable = 'off';
            app.CompleteLamp.Enable = 'on';
        end

        % Value changed function: QuickSaveSwitch
        function QuickSaveSwitchValueChanged(app, event)
            value = app.QuickSaveSwitch.Value;
            if strcmp(value,'Saving')
                trx(app.sex_index) = app.track;
                trx(setdiff([1,2],app.sex_index)) = app.file.trx(setdiff([1,2],app.sex_index));
                timestamps = [];
                save(strcat(app.dir,'\temp_fix_wing.mat'),'timestamps','trx')
                app.QuickSaveSwitch.Value = 'Saved';
            end
        end

        % Menu selected function: LoadMenu
        function LoadButtonPushed(app, event)
            [save_name, save_path] = uigetfile(app.dir,'*.mat');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            app.loaded_file = strcat(save_path,save_name);
            app.file = load(app.loaded_file);
            if app.file.trx(1).sex == 'M'
                app.sex_index = 1;
            elseif app.file.trx(2).sex == 'M'
                app.sex_index = 2;
            end
            app.track = app.file.trx(app.sex_index);
            app.wing_length = app.average_wing_length(app.track);
        end

        % Menu selected function: SaveasMenu
        function SaveasButtonPushed(app, event)
            [save_name, save_path] = uiputfile(app.dir,'*.mat');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            trx(app.sex_index) = app.track;
            trx(setdiff([1,2],app.sex_index)) = app.file.trx(setdiff([1,2],app.sex_index));
            timestamps = [];
            save(strcat(save_path,save_name),'timestamps','trx')
        end

        % Value changed function: BeginEditField
        function BeginEditFieldValueChanged(app, event)
            value = app.BeginEditField.Value;
            app.inter_begin = value;
        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            value = app.EndEditField.Value;
            app.inter_end = value;
            assert(app.inter_end >= app.inter_begin, 'End Index Cannot Be Smaller Than Begin Index')
        end

        % Button pushed function: InterpolateButton
        function InterpolateButtonPushed(app, event)
            app.interpolate()
        end

        % Button pushed function: SetBegin
        function SetBeginButtonPushed(app, event)
            app.BeginEditField.Value = app.frame_index;
            app.inter_begin = app.frame_index;
            [wingl_new_positoin, wingr_new_positoin] = get_new_position(app);
            app.inter_begin_anglel = app.get_orientation(wingl_new_positoin) - app.track.theta(app.frame_index);
            app.inter_begin_angler = app.get_orientation(wingr_new_positoin) - app.track.theta(app.frame_index);
        end

        % Button pushed function: SetEnd
        function SetEndButtonPushed(app, event)
            app.EndEditField.Value = app.frame_index;
            app.inter_end = app.frame_index;
            assert(app.inter_end >= app.inter_begin, 'End Index Cannot Be Smaller Than Begin Index')
            [wingl_new_positoin, wingr_new_positoin] = get_new_position(app);
            app.inter_end_anglel = app.get_orientation(wingl_new_positoin) - app.track.theta(app.frame_index);
            app.inter_end_angler = app.get_orientation(wingr_new_positoin) - app.track.theta(app.frame_index);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,'Are you sure you are exiting the program? Have you save the progress?','Exiting','Options',{'Yes','Cancel'});
            if strcmp(selection,'Yes')
                close
                delete(app)            
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.BrowseMenu = uimenu(app.Menu);
            app.BrowseMenu.MenuSelectedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.BrowseMenu.Text = 'Browse';
            app.LoadMenu = uimenu(app.Menu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadMenu.Text = 'Load';
            app.StartMenu = uimenu(app.Menu);
            app.StartMenu.MenuSelectedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartMenu.Text = 'Start';
            app.SaveasMenu = uimenu(app.Menu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasButtonPushed, true);
            app.SaveasMenu.Text = 'Save as';
            app.frame_indexSpinnerLabel = uilabel(app.UIFigure);
            app.frame_indexSpinnerLabel.Text = 'frame_index';
            app.frame_indexSpinner = uispinner(app.UIFigure);
            app.frame_indexSpinner.Limits = [1 Inf];
            app.frame_indexSpinner.RoundFractionalValues = 'on';
            app.frame_indexSpinner.ValueDisplayFormat = '%.0f';
            app.frame_indexSpinner.ValueChangedFcn = createCallbackFcn(app, @frame_indexSpinnerValueChanged, true);
            app.frame_indexSpinner.Value = 1;
            app.pathEditFieldLabel = uilabel(app.UIFigure);
            app.pathEditFieldLabel.Text = 'path';
            app.pathEditField = uieditfield(app.UIFigure, 'text');
            app.pathEditField.Editable = 'off';
            app.FixButton = uibutton(app.UIFigure, 'push');
            app.FixButton.ButtonPushedFcn = createCallbackFcn(app, @FixButtonPushed, true);
            app.FixButton.Text = 'Fix';
            app.WingDetectedSwitchLabel = uilabel(app.UIFigure);
            app.WingDetectedSwitchLabel.Text = 'Wing Detected';
            app.WingDetectedSwitch = uiswitch(app.UIFigure, 'toggle');
            app.WingDetectedSwitch.Items = {'Two', 'One'};
            app.WingDetectedSwitch.ValueChangedFcn = createCallbackFcn(app, @WingDetectedSwitchValueChanged, true);
            app.WingDetectedSwitch.Value = 'Two';
            app.CompleteLabel = uilabel(app.UIFigure);
            app.CompleteLabel.Text = 'Complete';
            app.CompleteLamp = uilamp(app.UIFigure);
            app.ProcessingLabel = uilabel(app.UIFigure);
            app.ProcessingLabel.Text = 'Processing';
            app.ProcessingLamp = uilamp(app.UIFigure);
            app.QuickSaveSwitchLabel = uilabel(app.UIFigure);
            app.QuickSaveSwitchLabel.Text = 'Quick Save';
            app.QuickSaveSwitch = uiswitch(app.UIFigure, 'toggle');
            app.QuickSaveSwitch.Items = {'Saving', 'Saved'};
            app.QuickSaveSwitch.ValueChangedFcn = createCallbackFcn(app, @QuickSaveSwitchValueChanged, true);
            app.QuickSaveSwitch.Value = 'Saved';
            app.ErrorFilterPanel = uipanel(app.UIFigure);
            app.ErrorFilterPanel.Title = 'Error Filter';
            app.ErrorTypeDropDownLabel = uilabel(app.ErrorFilterPanel);
            app.ErrorTypeDropDownLabel.Text = 'Error Type';
            app.ErrorTypeDropDown = uidropdown(app.ErrorFilterPanel);
            app.ErrorTypeDropDown.Items = {'All Video', 'Potential One Wing', 'Potential Wrong Extension', 'Potential Orientation'};
            app.ErrorTypeDropDown.Value = 'All Video';
            app.ErrorBoutListBoxLabel = uilabel(app.ErrorFilterPanel);
            app.ErrorBoutListBoxLabel.Text = 'Error Bout';
            app.ErrorBoutListBox = uilistbox(app.ErrorFilterPanel);
            app.RefreshButton = uibutton(app.ErrorFilterPanel, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.InterpolationPanel = uipanel(app.UIFigure);
            app.InterpolationPanel.Title = 'Interpolation';
            app.BeginEditFieldLabel = uilabel(app.InterpolationPanel);
            app.BeginEditFieldLabel.Text = 'Begin';
            app.BeginEditField = uieditfield(app.InterpolationPanel, 'numeric');
            app.BeginEditField.Limits = [1 Inf];
            app.BeginEditField.ValueDisplayFormat = '%.0f';
            app.BeginEditField.ValueChangedFcn = createCallbackFcn(app, @BeginEditFieldValueChanged, true);
            app.BeginEditField.Value = 1;
            app.EndEditFieldLabel = uilabel(app.InterpolationPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.InterpolationPanel, 'numeric');
            app.EndEditField.Limits = [1 Inf];
            app.EndEditField.ValueDisplayFormat = '%.0f';
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.EndEditField.Value = 1;
            app.InterpolateButton = uibutton(app.InterpolationPanel, 'push');
            app.InterpolateButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButton.Text = 'Interpolate';
            app.SetBegin = uibutton(app.InterpolationPanel, 'push');
            app.SetBegin.ButtonPushedFcn = createCallbackFcn(app, @SetBeginButtonPushed, true);
            app.SetBegin.Text = 'Set';
            app.SetEnd = uibutton(app.InterpolationPanel, 'push');
            app.SetEnd.ButtonPushedFcn = createCallbackFcn(app, @SetEndButtonPushed, true);
            app.SetEnd.Text = 'Set';
            app.VideoLengthEditFieldLabel = uilabel(app.UIFigure);
            app.VideoLengthEditFieldLabel.Text = 'Video Length';
            app.VideoLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.VideoLengthEditField.ValueDisplayFormat = '%.0f';
            app.VideoLengthEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Fly_Wing_Fixer_2017b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end